classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        Den3FieldEditField_2       matlab.ui.control.EditField
        Den3FieldEditField_2Label  matlab.ui.control.Label
        Num3FieldEditField_2       matlab.ui.control.EditField
        Num3FieldEditField_2Label  matlab.ui.control.Label
        Den2FieldEditField_2       matlab.ui.control.EditField
        Den2FieldEditField_2Label  matlab.ui.control.Label
        Num2FieldEditField_2       matlab.ui.control.EditField
        Num2FieldEditField_2Label  matlab.ui.control.Label
        Den1FieldEditField_2       matlab.ui.control.EditField
        Den1FieldEditField_2Label  matlab.ui.control.Label
        Num1FieldEditField_2       matlab.ui.control.EditField
        Num1FieldEditField_2Label  matlab.ui.control.Label
        ValideretlancerButton      matlab.ui.control.Button
        IEditField                 matlab.ui.control.NumericEditField
        IEditFieldLabel            matlab.ui.control.Label
        PEditField                 matlab.ui.control.NumericEditField
        PEditFieldLabel            matlab.ui.control.Label
        ConsigneEditField          matlab.ui.control.NumericEditField
        ConsigneEditFieldLabel     matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Timer % Description
    end
    
    methods (Access = public)
        function updatePlot(app)
            %disp(get_param('Design2simulink/Scope', 'DataLoggingVariableName'));
            %disp(get_param('Design2simulink/Scope', 'DataLogging'));
            %uialert(app.UIFigure, ['Erreur dans Simulink : ' 0], 'Erreur');
            simulationTime = get_param('Design2simulink', 'SimulationTime');
            signal_consigne = evalin('base', 'out').signal_sortie;%simOut.get('signal_commande');
            signal_sortie = evalin('base', 'out').signal_commande;
            %signal_consigne = evalin('base', 'ScopeData');
            %signal_sortie = evalin('base', 'ScopeData');
            N = length(signal_sortie);
            temps = linspace(0, simulationTime, N);
            % Get the scope data from MATLAB workspace and plot it
            plot(app.UIAxes, temps, signal_consigne, 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, temps, signal_sortie, '--', 'Linewidth', 1.5);
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, {'Consigne', 'Sortie réelle'}, 'Location', 'best');
            title(app.UIAxes, 'Réponse du système');
            xlabel(app.UIAxes, 'Temps (s)');
            ylabel(app.UIAxes, 'Température');
            grid(app.UIAxes, 'on');

            % Créer une structure avec les paramètres
            params = struct( ...
            'P', P, ...
            'I', I, ...
            'Consigne', Consigne, ...
            'TF1', struct('Numerator', num1, 'Denominator', den1), ...
            'TF2', struct('Numerator', num2, 'Denominator', den2), ...
            'TF3', struct('Numerator', num3, 'Denominator', den3), ...
            'Horodatage', datetime("now", 'yyyy-mm-dd_HH-MM-SS') ...
            );

            % Convertir en JSON
            jsonStr = jsonencode(params);

            % Enregistrer dans un fichier
            filename = ['parametres_simulation_' datetime("now", 'yyyymmdd_HHMMSS') '.json'];
            fid = fopen(filename, 'w');
            if fid ~= -1
            fwrite(fid, jsonStr, 'char');
            fclose(fid);
            disp(['Paramètres sauvegardés dans : ' filename]);
            else
            uialert(app.UIFigure, 'Erreur lors de l''écriture du fichier JSON', 'Erreur');
            end
            %start(app.Timer);
             % Update the GUI accordingly. In this c
        end
        
        function updateGUI(app, varargin)
             % Create an object that gets the run-time value of the specified block
             %rto = get_param([bdroot,'/DisplayBox'],'RuntimeObject');
            plot(app.UIAxes, temps, signal_consigne, 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, temps, signal_sortie, '--', 'Linewidth', 1.5);
            hold(app.UIAxes, 'off');
            legend(app.UIAxes, {'Consigne', 'Sortie réelle'}, 'Location', 'best');
            title(app.UIAxes, 'Réponse du système');
            xlabel(app.UIAxes, 'Temps (s)');
            ylabel(app.UIAxes, 'Température');
            grid(app.UIAxes, 'on');
             % Update the GUI accordingly. In this case a the value of an EditField is being updated.
             %app.EditField.Value = rto.InputPort(1).Data;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~)updatePlot(app));
            start(app.Timer);
        end

        % Button down function: UIAxes, UIFigure
        function UIFigureButtonDown(app, event)
        
        %end

        %function ValideretlancerButtonPushed(app, event)
    
         %   end


        end

        % Button pushed function: ValideretlancerButton
        function ValideretlancerButtonPushed(app, event)

            % Lire les valeurs
                P = app.PEditField.Value;
                I = app.IEditField.Value;
                Consigne = app.ConsigneEditField.Value;

    % Lire les TF
                num1 = eval(app.Num1FieldEditField_2.Value);
                den1 = eval(app.Den1FieldEditField_2.Value);
                num2 = eval(app.Num2FieldEditField_2.Value);
                den2 = eval(app.Den2FieldEditField_2.Value);
                num3 = eval(app.Num3FieldEditField_2.Value);
                den3 = eval(app.Den3FieldEditField_2.Value);

    % Vérification
                if isnan(P) || isnan(I) || isnan(Consigne)
                    uialert(app.UIFigure, 'Les valeurs entrées ne sont pas valides', 'Erreur');
                    return;
                end

    % Mettre à jour Simulink
                assignin('base', 'P', P);
                assignin('base', 'I', I);
                assignin('base', 'Consigne', Consigne);

                try
                    set_param("Design2simulink/PID Controller", 'P', num2str(P), 'I', num2str(I));
                    set_param('Design2simulink/Step', 'After', num2str(Consigne));
                    %uialert(app.UIFigure, ['hello : ' [num2str(num1)]], 'Erreur');
                    set_param('Design2simulink/Procédé/Plaque/T1', 'Numerator', mat2str(num1), 'Denominator', mat2str(den1));
                    set_param('Design2simulink/Procédé/Plaque/T2', 'Numerator', mat2str(num2), 'Denominator', mat2str(den2));
                    set_param('Design2simulink/Procédé/Plaque/T3', 'Numerator', mat2str(num3), 'Denominator', mat2str(den3));

                catch ME

                    uialert(app.UIFigure, ['Erreur dans Simulink : ' ME.message], 'Erreur');
                    return;
                end

    % Lancer la simulation
               
                
                set_param('Design2simulink', 'SimulationCommand', 'start');
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Réponse du système')
            xlabel(app.UIAxes, 'Temps (s)')
            ylabel(app.UIAxes, 'Température (°C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ConsigneEditFieldLabel = uilabel(app.UIFigure);
            app.ConsigneEditFieldLabel.Text = 'Consigne';
            app.ConsigneEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConsigneEditField.Value = 28;
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Value = 0.5;
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Value = 0.005;
            app.ValideretlancerButton = uibutton(app.UIFigure, 'push');
            app.ValideretlancerButton.ButtonPushedFcn = createCallbackFcn(app, @ValideretlancerButtonPushed, true);
            app.ValideretlancerButton.Text = 'Valider et lancer';
            app.Num1FieldEditField_2Label = uilabel(app.UIFigure);
            app.Num1FieldEditField_2Label.Text = 'Num1Field';
            app.Num1FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Num1FieldEditField_2.Value = '[0.48]';
            app.Den1FieldEditField_2Label = uilabel(app.UIFigure);
            app.Den1FieldEditField_2Label.Text = 'Den1Field';
            app.Den1FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Den1FieldEditField_2.Value = '[104 1]';
            app.Num2FieldEditField_2Label = uilabel(app.UIFigure);
            app.Num2FieldEditField_2Label.Text = 'Num2Field';
            app.Num2FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Num2FieldEditField_2.Value = '[0.79]';
            app.Den2FieldEditField_2Label = uilabel(app.UIFigure);
            app.Den2FieldEditField_2Label.Text = 'Den2Field';
            app.Den2FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Den2FieldEditField_2.Value = '[30 1]';
            app.Num3FieldEditField_2Label = uilabel(app.UIFigure);
            app.Num3FieldEditField_2Label.Text = 'Num3Field';
            app.Num3FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Num3FieldEditField_2.Value = '[0.88]';
            app.Den3FieldEditField_2Label = uilabel(app.UIFigure);
            app.Den3FieldEditField_2Label.Text = 'Den3Field';
            app.Den3FieldEditField_2 = uieditfield(app.UIFigure, 'text');
            app.Den3FieldEditField_2.Value = '[30 1]';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2025-03-31 at 11-45-48 The MathWorks Logo is an Eigenfunction of the Wave Equation - MATLAB & Simulink.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end