classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ButtonGroup_2                  matlab.ui.container.ButtonGroup
        PerturbationLabel              matlab.ui.control.Label
        FinsEditField                  matlab.ui.control.NumericEditField
        FinsEditFieldLabel             matlab.ui.control.Label
        PuissanceWEditField            matlab.ui.control.NumericEditField
        PuissanceWEditFieldLabel       matlab.ui.control.Label
        DbutsEditField                 matlab.ui.control.NumericEditField
        DbutsEditFieldLabel            matlab.ui.control.Label
        ButtonGroup                    matlab.ui.container.ButtonGroup
        RgulateurLabel                 matlab.ui.control.Label
        IEditField                     matlab.ui.control.NumericEditField
        IEditFieldLabel                matlab.ui.control.Label
        PEditField                     matlab.ui.control.NumericEditField
        PEditFieldLabel                matlab.ui.control.Label
        ConsigneCEditField             matlab.ui.control.NumericEditField
        ConsigneCLabel                 matlab.ui.control.Label
        PuissanceEditField             matlab.ui.control.NumericEditField
        PuissanceEditFieldLabel        matlab.ui.control.Label
        RguldbutsEditField             matlab.ui.control.NumericEditField
        RguldbutsEditFieldLabel        matlab.ui.control.Label
        BoucleouverteButton            matlab.ui.control.RadioButton
        BouclefermeButton              matlab.ui.control.RadioButton
        TabGroup2                      matlab.ui.container.TabGroup
        TempraturesTab                 matlab.ui.container.Tab
        TabGroup                       matlab.ui.container.TabGroup
        UT1Tab                         matlab.ui.container.Tab
        DnominateurEditField_2         matlab.ui.control.EditField
        DnominateurEditField_2Label    matlab.ui.control.Label
        NumrateurEditField_2           matlab.ui.control.EditField
        NumrateurEditField_2Label      matlab.ui.control.Label
        T1T2Tab                        matlab.ui.container.Tab
        DnominateurEditField           matlab.ui.control.EditField
        DnominateurEditFieldLabel      matlab.ui.control.Label
        NumrateurEditField             matlab.ui.control.EditField
        NumrateurEditFieldLabel        matlab.ui.control.Label
        T2T3Tab                        matlab.ui.container.Tab
        DnominateurEditField_3         matlab.ui.control.EditField
        DnominateurEditField_3Label    matlab.ui.control.Label
        NumrateurEditField_3           matlab.ui.control.EditField
        NumrateurEditField_3Label      matlab.ui.control.Label
        graph_temp                     matlab.ui.control.UIAxes
        RsistancesTab                  matlab.ui.container.Tab
        graph_res                      matlab.ui.control.UIAxes
        TensionsTab                    matlab.ui.container.Tab
        graph_tens                     matlab.ui.control.UIAxes
        RgulateurTab                   matlab.ui.container.Tab
        ADCbitsEditField_2             matlab.ui.control.NumericEditField
        ADCbitsEditField_2Label        matlab.ui.control.Label
        graph_reg                      matlab.ui.control.UIAxes
        PWMTab                         matlab.ui.container.Tab
        DACbitsEditField_2             matlab.ui.control.NumericEditField
        DACbitsEditField_2Label        matlab.ui.control.Label
        graph_pwm                      matlab.ui.control.UIAxes
        AmpliTab                       matlab.ui.container.Tab
        graph_ampli                    matlab.ui.control.UIAxes
        IdentificationTab              matlab.ui.container.Tab
        ChoisirunfichierdchelonButton  matlab.ui.control.Button
        graph_id_entre                 matlab.ui.control.UIAxes
        graph_identification           matlab.ui.control.UIAxes
        TempssimsEditField             matlab.ui.control.NumericEditField
        TempssimsEditFieldLabel        matlab.ui.control.Label
        ThermistancesLabel             matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        ValideretlancerButton          matlab.ui.control.Button
    end

    
    properties (Access = private)
        Timer % Description
    end
    
    methods (Access = public)
        function startSimulation(app, tempsSimulation)
            f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, 'Simulation en cours');
            %f.CloseRequestFcn = @(src,event)my_closereq(src);

            %function my_closereq(fig)
               % delete(f);
              %  f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, 'Simulation en cours');
             %   return
            %end

            set_param('Design2simulink', 'SimulationCommand', 'start');
            pause(1);
            while get_param('Design2simulink', 'SimulationTime') < tempsSimulation && get_param('Design2simulink', 'SimulationTime') > 1
                if ishandle(f)
                    f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, f, 'Simulation en cours');
                else
                    f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, 'Simulation en cours');                    
                end
                pause(1);
            end
           
            signal_consigne = evalin('base', 'out.consigne');%simOut.get('signal_commande');
            signal_t1 = evalin('base', 'out.T_1');
            signal_t2 = evalin('base', 'out.T_2');
            signal_sortie = evalin('base', 'out.final');
            signal_t3_est = evalin('base', 'out.T_3_est');
            N = length(signal_sortie);
            temps = linspace(0, tempsSimulation, N);
            
            
            plot(app.graph_temp, temps, signal_consigne, 'b', 'LineWidth', 1.5);
            hold(app.graph_temp, 'on');
            
            plot(app.graph_temp, temps, signal_t1, 'LineWidth', 1.5);
            plot(app.graph_temp, temps, signal_t2, 'LineWidth', 1.5)
            plot(app.graph_temp, temps, signal_sortie, '.', 'Linewidth', 1.5);
            plot(app.graph_temp, temps, signal_t3_est, '-.', 'LineWidth', 1.5);
            
            hold(app.graph_temp, 'off');


            legend(app.graph_temp, {'Consigne', 'T1', 'T2', 'T3', 'T3 est.'}, 'Location', 'best');
            
            grid(app.graph_temp, 'on');


            sortie_adc = evalin('base', 'out.Sortie_ADC');
            entree_reg = evalin('base', 'out.Entree_Reg');
            
            plot(app.graph_reg, temps, sortie_adc, 'r-.', 'LineWidth', 1.5);
            
            hold(app.graph_reg, 'on');
            plot(app.graph_reg, temps, entree_reg, 'b', 'LineWidth', 1.5)
            hold(app.graph_reg, 'off');


            legend(app.graph_reg, {"Sortie de l'ADC", 'Entrée du régulateur'}, 'Location', 'best');
            
            grid(app.graph_reg, 'on');


            sortie_dac = evalin('base', 'out.Sortie_DAC');
            sortie_pwm = evalin('base', 'out.Sortie_PWM');
            
            plot(app.graph_pwm, temps, sortie_dac, 'r-.', 'LineWidth', 1.5);
            
            hold(app.graph_pwm, 'on');
            plot(app.graph_pwm, temps, sortie_pwm, 'b', 'LineWidth', 1.5);
            hold(app.graph_pwm, 'off');


            legend(app.graph_pwm, {'Sortie du DAC', 'Sortie du PWM filtrée'}, 'Location', 'best');
            
            grid(app.graph_pwm, 'on');

            sortie_source = evalin('base', 'out.Sortie_Source');
            
            plot(app.graph_ampli, temps, sortie_source, 'b', 'LineWidth', 1.5);


            legend(app.graph_ampli, {'Sortie de la source'}, 'Location', 'best');
            
            grid(app.graph_ampli, 'on');

            close(f);
            
            
            % Créer une structure avec les paramètres
            %params = struct( ...
            %'P', P, ...
            %'I', I, ...
            %'Consigne', Consigne, ...
            %'TF1', struct('Numerator', num1, 'Denominator', den1), ...
            %'TF2', struct('Numerator', num2, 'Denominator', den2), ...
            %'TF3', struct('Numerator', num3, 'Denominator', den3), ...
            %'Horodatage', datetime("now", 'yyyy-mm-dd_HH-MM-SS') ...
            %);

            % Convertir en JSON
            %jsonStr = jsonencode(params);

            % Enregistrer dans un fichier
            %filename = ['parametres_simulation_' datetime("now", 'yyyymmdd_HHMMSS') '.json'];
            %fid = fopen(filename, 'w');
            %if fid ~= -1
             %   fwrite(fid, jsonStr, 'char');
              %  fclose(fid);
               % disp(['Paramètres sauvegardés dans : ' filename]);
            %else
             %   uialert(app.UIFigure, 'Erreur lors de l''écriture du fichier JSON', 'Erreur');
            %end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'switchBoucle', [0 1]);
            assignin('base', 'bits_DAC', [0 10]);
            assignin('base', 'bits_ADC', [0 12]);
            assignin('base', 'Perturbation', 1);
            app.RguldbutsEditField.Enable = 'on';
            app.ConsigneCEditField.Enable = 'on';
            app.PEditField.Enable = 'on';
            app.IEditField.Enable = 'on';
            app.PuissanceEditField.Enable = 'off';
        end

        % Button down function: UIFigure, graph_temp
        function UIFigureButtonDown(app, event)
        
        %end

        %function ValideretlancerButtonPushed(app, event)
    
         %   end


        end

        % Button pushed function: ValideretlancerButton
        function ValideretlancerButtonPushed(app, event)

            % Lire les valeurs
                P = app.PEditField.Value;
                I = app.IEditField.Value;
                Consigne = app.ConsigneCEditField.Value;
                tempsSimulation = app.TempssimsEditField.Value;

    % Lire les TF
                num1 = eval(app.NumrateurEditField_2.Value);
                den1 = eval(app.DnominateurEditField_2.Value);
                num2 = eval(app.NumrateurEditField.Value);
                den2 = eval(app.DnominateurEditField.Value);
                num3 = eval(app.NumrateurEditField_3.Value);
                den3 = eval(app.DnominateurEditField_3.Value);

    % Vérification
                if isnan(P) || isnan(I) || isnan(Consigne) || isnan(tempsSimulation)
                    uialert(app.UIFigure, 'Les valeurs entrées ne sont pas valides', 'Erreur');
                    return;
                end

    % Mettre à jour Simulink
                assignin('base', 'P', P);
                assignin('base', 'I', I);
                assignin('base', 'Consigne', Consigne);
                set_param('Design2simulink', 'StopTime', num2str(tempsSimulation));

                assignin('base', 'percentPower', [0 app.PuissanceEditField.Value]);

                %assignin('base', 'Perturbation', app.PuissanceWEditField);
                set_param('Design2simulink/Procédé/Plaque/Step', 'After', num2str(app.PuissanceWEditField.Value));
                set_param('Design2simulink/Procédé/Plaque/Step', 'Time', num2str(app.DbutsEditField.Value));

                try
                    set_param('Design2simulink/Régulateur/PID Controller', 'P', num2str(P), 'I', num2str(I));
                    set_param('Design2simulink/Step', 'After', num2str(Consigne));
                    set_param('Design2simulink/Step', 'Time', num2str(app.RguldbutsEditField.Value));
                    %uialert(app.UIFigure, ['hello : ' [num2str(num1)]], 'Erreur');
                    set_param('Design2simulink/Procédé/Plaque/T1', 'Numerator', mat2str(num1), 'Denominator', mat2str(den1));
                    set_param('Design2simulink/Procédé/Plaque/T2', 'Numerator', mat2str(num2), 'Denominator', mat2str(den2));
                    set_param('Design2simulink/Procédé/Plaque/T3', 'Numerator', mat2str(num3), 'Denominator', mat2str(den3));

                catch ME

                    uialert(app.UIFigure, ['Erreur dans Simulink : ' ME.message], 'Erreur');
                    return;
                end

                % Lancer la simulation
                startSimulation(app, tempsSimulation);
                %app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~)updatePlot(app));
                %start(app.Timer);
               
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            if selectedButton == app.BoucleouverteButton
                assignin('base', 'switchBoucle', [0 -1]);
                app.RguldbutsEditField.Enable = 'off';
                app.ConsigneCEditField.Enable = 'off';
                app.PEditField.Enable = 'off';
                app.IEditField.Enable = 'off';
                app.PuissanceEditField.Enable = 'on';
            else
                assignin('base', 'switchBoucle', [0 1]);
                app.RguldbutsEditField.Enable = 'on';
                app.ConsigneCEditField.Enable = 'on';
                app.PEditField.Enable = 'on';
                app.IEditField.Enable = 'on';
                app.PuissanceEditField.Enable = 'off';
            end
        end

        % Value changed function: DACbitsEditField_2
        function DACbitsEditField_2ValueChanged(app, event)
            assignin('base', 'bits_DAC', [0 app.DACbitsEditField_2.Value]);
        end

        % Value changed function: ADCbitsEditField_2
        function ADCbitsEditField_2ValueChanged(app, event)
            assignin('base', 'bits_ADC', [0 app.ADCbitsEditField_2.Value]);
        end

        % Button pushed function: ChoisirunfichierdchelonButton
        function ChoisirunfichierdchelonButtonPushed(app, event)
            [file, path] = uigetfile({'*.txt;*.csv', 'Text and CSV Files (*.txt, *.csv)'}, ...
                             'Select a File');

            if file ~= 0
                data = importdata(fullfile(path, file));
                %disp(data);
                temps = data(:, 1);
                commande = data(:, 2);% - data(1, 2);
                perturbation = data(:, 3);
                t1 = data(:, 4);
                t2 = data(:, 5);
                t3 = data(:, 6);

                plot(app.graph_id_entre, temps, commande, 'LineWidth', 1.5);
                hold(app.graph_id_entre, 'on');
                plot(app.graph_id_entre, temps, perturbation, 'LineWidth', 1.5);
                hold(app.graph_id_entre, 'off');
                legend(app.graph_id_entre, {'Commande (A)', 'Perturbation (W)'}, 'Location', 'best');

                plot(app.graph_identification, temps, t1, 'LineWidth', 1.5)
                hold(app.graph_identification, 'on');
                plot(app.graph_identification, temps, t2, '.', 'Linewidth', 1.5);
                plot(app.graph_identification, temps, t3, '-.', 'LineWidth', 1.5);
                legend(app.graph_id_entre, {'T1', 'T2', 'T3'}, 'Location', 'best');
                hold(app.graph_identification, 'off');

                choice = questdlg("Voulez-vous appliquer l'identification trouvée ?", ...  % Question text
                      'Confirmation', ...           % Title of the dialog box
                      'Oui', 'Non', 'Oui');          % Button options, default is 'Yes'

                switch choice
                    case 'Oui'
                        
                    case 'Non'
                        
                    otherwise
                        disp('No action taken');
                end
            else
                disp('File selection was canceled');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ValideretlancerButton = uibutton(app.UIFigure, 'push');
            app.ValideretlancerButton.ButtonPushedFcn = createCallbackFcn(app, @ValideretlancerButtonPushed, true);
            app.ValideretlancerButton.Text = 'Valider et lancer';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'bab.jpeg');
            app.ThermistancesLabel = uilabel(app.UIFigure);
            app.ThermistancesLabel.Text = 'Thermistances :';
            app.TempssimsEditFieldLabel = uilabel(app.UIFigure);
            app.TempssimsEditFieldLabel.Text = 'Temps sim (s)';
            app.TempssimsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempssimsEditField.Value = 2000;
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.TempraturesTab = uitab(app.TabGroup2);
            app.TempraturesTab.Title = 'Températures';
            app.graph_temp = uiaxes(app.TempraturesTab);
            title(app.graph_temp, 'Réponse du système')
            xlabel(app.graph_temp, 'Temps (s)')
            ylabel(app.graph_temp, 'Température (°C)')
            zlabel(app.graph_temp, 'Z')
            app.graph_temp.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.TabGroup = uitabgroup(app.TempraturesTab);
            app.UT1Tab = uitab(app.TabGroup);
            app.UT1Tab.Title = 'U->T1';
            app.NumrateurEditField_2Label = uilabel(app.UT1Tab);
            app.NumrateurEditField_2Label.Text = 'Numérateur ';
            app.NumrateurEditField_2 = uieditfield(app.UT1Tab, 'text');
            app.NumrateurEditField_2.Value = '[-8.07]';
            app.DnominateurEditField_2Label = uilabel(app.UT1Tab);
            app.DnominateurEditField_2Label.Text = 'Dénominateur ';
            app.DnominateurEditField_2 = uieditfield(app.UT1Tab, 'text');
            app.DnominateurEditField_2.Value = '[90.09 1]';
            app.T1T2Tab = uitab(app.TabGroup);
            app.T1T2Tab.Title = 'T1->T2';
            app.NumrateurEditFieldLabel = uilabel(app.T1T2Tab);
            app.NumrateurEditFieldLabel.Text = 'Numérateur ';
            app.NumrateurEditField = uieditfield(app.T1T2Tab, 'text');
            app.NumrateurEditField.Value = '[0.789]';
            app.DnominateurEditFieldLabel = uilabel(app.T1T2Tab);
            app.DnominateurEditFieldLabel.Text = 'Dénominateur';
            app.DnominateurEditField = uieditfield(app.T1T2Tab, 'text');
            app.DnominateurEditField.Value = '[40.49 1]';
            app.T2T3Tab = uitab(app.TabGroup);
            app.T2T3Tab.Title = 'T2->T3';
            app.NumrateurEditField_3Label = uilabel(app.T2T3Tab);
            app.NumrateurEditField_3Label.Text = 'Numérateur ';
            app.NumrateurEditField_3 = uieditfield(app.T2T3Tab, 'text');
            app.NumrateurEditField_3.Value = '[0.921]';
            app.DnominateurEditField_3Label = uilabel(app.T2T3Tab);
            app.DnominateurEditField_3Label.Text = 'Dénominateur ';
            app.DnominateurEditField_3 = uieditfield(app.T2T3Tab, 'text');
            app.DnominateurEditField_3.Value = '[20.28 1]';
            app.RsistancesTab = uitab(app.TabGroup2);
            app.RsistancesTab.Title = 'Résistances';
            app.graph_res = uiaxes(app.RsistancesTab);
            title(app.graph_res, 'Réponse du système')
            xlabel(app.graph_res, 'Temps (s)')
            ylabel(app.graph_res, 'Résistance (Ohm)')
            zlabel(app.graph_res, 'Z')
            app.TensionsTab = uitab(app.TabGroup2);
            app.TensionsTab.Title = 'Tensions';
            app.graph_tens = uiaxes(app.TensionsTab);
            title(app.graph_tens, 'Réponse du système')
            xlabel(app.graph_tens, 'Temps (s)')
            ylabel(app.graph_tens, 'Tension (V)')
            zlabel(app.graph_tens, 'Z')
            app.RgulateurTab = uitab(app.TabGroup2);
            app.RgulateurTab.Title = 'Régulateur';
            app.graph_reg = uiaxes(app.RgulateurTab);
            title(app.graph_reg, 'Régulateur')
            xlabel(app.graph_reg, 'Temps (s)')
            ylabel(app.graph_reg, 'Tension (V)')
            zlabel(app.graph_reg, 'Z')
            app.ADCbitsEditField_2Label = uilabel(app.RgulateurTab);
            app.ADCbitsEditField_2Label.Text = 'ADC (bits)';
            app.ADCbitsEditField_2 = uieditfield(app.RgulateurTab, 'numeric');
            app.ADCbitsEditField_2.ValueChangedFcn = createCallbackFcn(app, @ADCbitsEditField_2ValueChanged, true);
            app.ADCbitsEditField_2.Value = 12;
            app.PWMTab = uitab(app.TabGroup2);
            app.PWMTab.Title = 'PWM';
            app.graph_pwm = uiaxes(app.PWMTab);
            title(app.graph_pwm, 'PWM')
            xlabel(app.graph_pwm, 'Temps (s)')
            ylabel(app.graph_pwm, 'Tenson (V)')
            zlabel(app.graph_pwm, 'Z')
            app.DACbitsEditField_2Label = uilabel(app.PWMTab);
            app.DACbitsEditField_2Label.Text = 'DAC (bits)';
            app.DACbitsEditField_2 = uieditfield(app.PWMTab, 'numeric');
            app.DACbitsEditField_2.ValueChangedFcn = createCallbackFcn(app, @DACbitsEditField_2ValueChanged, true);
            app.DACbitsEditField_2.Value = 10;
            app.AmpliTab = uitab(app.TabGroup2);
            app.AmpliTab.Title = 'Ampli';
            app.graph_ampli = uiaxes(app.AmpliTab);
            title(app.graph_ampli, 'Source du TEC')
            xlabel(app.graph_ampli, 'Temps (s)')
            ylabel(app.graph_ampli, 'Courant (A)')
            zlabel(app.graph_ampli, 'Z')
            app.IdentificationTab = uitab(app.TabGroup2);
            app.IdentificationTab.Title = 'Identification';
            app.graph_identification = uiaxes(app.IdentificationTab);
            title(app.graph_identification, 'Réponse du système')
            xlabel(app.graph_identification, 'Temps (s)')
            ylabel(app.graph_identification, 'Température (°C)')
            zlabel(app.graph_identification, 'Z')
            app.graph_id_entre = uiaxes(app.IdentificationTab);
            title(app.graph_id_entre, 'Entrées du système')
            xlabel(app.graph_id_entre, 'Temps (s)')
            ylabel(app.graph_id_entre, 'Entrées')
            zlabel(app.graph_id_entre, 'Z')
            app.ChoisirunfichierdchelonButton = uibutton(app.IdentificationTab, 'push');
            app.ChoisirunfichierdchelonButton.ButtonPushedFcn = createCallbackFcn(app, @ChoisirunfichierdchelonButtonPushed, true);
            app.ChoisirunfichierdchelonButton.Text = 'Choisir un fichier d''échelon';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.BouclefermeButton = uiradiobutton(app.ButtonGroup);
            app.BouclefermeButton.Text = 'Boucle fermée';
            app.BouclefermeButton.Value = true;
            app.BoucleouverteButton = uiradiobutton(app.ButtonGroup);
            app.BoucleouverteButton.Text = 'Boucle ouverte';
            app.RguldbutsEditFieldLabel = uilabel(app.ButtonGroup);
            app.RguldbutsEditFieldLabel.Text = 'Régul début (s)';
            app.RguldbutsEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.RguldbutsEditField.Value = 500;
            app.PuissanceEditFieldLabel = uilabel(app.ButtonGroup);
            app.PuissanceEditFieldLabel.Text = 'Puissance (%)';
            app.PuissanceEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.ConsigneCLabel = uilabel(app.ButtonGroup);
            app.ConsigneCLabel.Text = 'Consigne (°C)';
            app.ConsigneCEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.ConsigneCEditField.Value = 25;
            app.PEditFieldLabel = uilabel(app.ButtonGroup);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.PEditField.Value = 0.53;
            app.IEditFieldLabel = uilabel(app.ButtonGroup);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.ButtonGroup, 'numeric');
            app.IEditField.Value = 0.0049;
            app.RgulateurLabel = uilabel(app.ButtonGroup);
            app.RgulateurLabel.Text = 'Régulateur';
            app.ButtonGroup_2 = uibuttongroup(app.UIFigure);
            app.DbutsEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.DbutsEditFieldLabel.Text = 'Début (s)';
            app.DbutsEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.DbutsEditField.Value = 600;
            app.PuissanceWEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.PuissanceWEditFieldLabel.Text = 'Puissance (W)';
            app.PuissanceWEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.FinsEditFieldLabel = uilabel(app.ButtonGroup_2);
            app.FinsEditFieldLabel.Text = 'Fin (s)';
            app.FinsEditField = uieditfield(app.ButtonGroup_2, 'numeric');
            app.FinsEditField.Value = 2000;
            app.PerturbationLabel = uilabel(app.ButtonGroup_2);
            app.PerturbationLabel.Text = 'Perturbation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end